classdef PatientsDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        Label                     matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        BloodPressureSwitchLabel  matlab.ui.control.Label
        BloodPressureSwitch       matlab.ui.control.Switch
        Panel2                    matlab.ui.container.Panel
        FemaleCheckBox            matlab.ui.control.CheckBox
        MaleCheckBox              matlab.ui.control.CheckBox
        ButtonGroup               matlab.ui.container.ButtonGroup
        ScatterButton             matlab.ui.control.RadioButton
        HistogramButton           matlab.ui.control.RadioButton
        BinWidthSliderLabel       matlab.ui.control.Label
        BinWidthSlider            matlab.ui.control.Slider
        Tab2                      matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
    end

    % The app displays the data by using the scatter plot and histogram.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end

        function annotateScatterPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = 'Weight';
            app.UIAxes.YLabel.String = app.BloodPressureSwitch.Value;
            % Turn on title
            app.Label.Visible = 'on';
            % Dont show the histogram slider
            app.BinWidthSliderLabel.Visible = 'off';
            app.BinWidthSlider.Visible = 'off';
        end
        
        function annotateHistogram(app)
            % Turn off title
            app.Label.Visible = 'off';
            
            % Update X and Y Labels
            app.UIAxes.XLabel.String = app.BloodPressureSwitch.Value;
            app.UIAxes.YLabel.String = '# of Patients';
            
            % Show histogram slider
            app.BinWidthSliderLabel.Visible = 'on';
            app.BinWidthSlider.Visible = 'on';
        end
        
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            load patients.mat
            
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
            app.UITable.Data = table2cell(app.Data);
            app.BinWidth = app.BinWidthSlider.Value;
            
            % Update the axes with the corresponding data.
            updateSelectedGenders(app)
            refreshplot(app)
        end

        % Value changing function: BinWidthSlider
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Callback function: BinWidthSlider, BloodPressureSwitch, 
        % ButtonGroup, UITable
        function refreshplot(app, event)
            Genders = app.SelectedGenders;
            Colors = app.SelectedColors;
            
            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            app.Histogram = gobjects(0);
            
            % Select relevant segment of data
            xdata = app.Data.Weight;
            ydata = app.Data.(app.BloodPressureSwitch.Value);
            
            % Create either a scatter plot or histogram, based on selection
            switch app.ButtonGroup.SelectedObject.Text
            
                case 'Scatter'
                    % Build a scatter plot for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = app.Data.Gender == Genders(ii);
                        scatter(app.UIAxes,xdata(selectedpatients),ydata(selectedpatients),Colors{ii});
                    end
                    annotateScatterPlot(app)
                    
                case 'Histogram'
                    % Build a histogram for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = app.Data.Gender == Genders(ii);
                        NBins = numhistbins(app,ydata(selectedpatients));
                        h = histogram(app.UIAxes,ydata(selectedpatients),NBins,'BinLimits',[floor(min(ydata)) ceil(max(ydata))]);
                        h.EdgeColor = Colors{ii};
                        h.FaceColor = Colors{ii};
                        app.Histogram = [app.Histogram h];
                    end
                    annotateHistogram(app)
                
            end            
        end

        % Value changed function: FemaleCheckBox, MaleCheckBox
        function updateSelectedGenders(app, event)
            % List which genders and colors to use
            Genders = [];
            Colors = [];
            if app.MaleCheckBox.Value
                Genders = "Male";
                Colors = "blue";
            end
            if app.FemaleCheckBox.Value
                Genders = [Genders "Female"];
                Colors = [Colors "red"];
            end
            
            if isempty(Genders)
                % Disable the switches and buttons if they were on
                app.BloodPressureSwitch.Enable = 'off';
                app.ScatterButton.Enable = 'off';
                app.HistogramButton.Enable = 'off';
                app.BinWidthSliderLabel.Enable = 'off';
                app.BinWidthSlider.Enable = 'off';
            else
                % Enable the switches and buttons if they were off
                app.BloodPressureSwitch.Enable = 'on';
                app.ScatterButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
                app.BinWidthSliderLabel.Enable = 'on';
                app.BinWidthSlider.Enable = 'on';
            end
            app.SelectedGenders = Genders;
            app.SelectedColors = Colors;
            refreshplot(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Patients Display';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Plot';
            app.UIAxes = uiaxes(app.Tab1);
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Diastolic')
            app.Label = uilabel(app.Tab1);
            app.Label.Text = 'Data Visualization';
            app.Panel = uipanel(app.Tab1);
            app.BloodPressureSwitchLabel = uilabel(app.Panel);
            app.BloodPressureSwitchLabel.Text = 'Blood Pressure';
            app.BloodPressureSwitch = uiswitch(app.Panel, 'slider');
            app.BloodPressureSwitch.Items = {'Systolic', 'Diastolic'};
            app.BloodPressureSwitch.ValueChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.BloodPressureSwitch.Value = 'Systolic';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = 'Gender';
            app.FemaleCheckBox = uicheckbox(app.Panel2);
            app.FemaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.FemaleCheckBox.Text = 'Female';
            app.MaleCheckBox = uicheckbox(app.Panel2);
            app.MaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.MaleCheckBox.Text = 'Male';
            app.MaleCheckBox.Value = true;
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.ButtonGroup.Title = 'Plotting Options';
            app.ScatterButton = uiradiobutton(app.ButtonGroup);
            app.ScatterButton.Text = 'Scatter';
            app.ScatterButton.Value = true;
            app.HistogramButton = uiradiobutton(app.ButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.BinWidthSliderLabel = uilabel(app.Tab1);
            app.BinWidthSliderLabel.Text = 'Bin Width';
            app.BinWidthSlider = uislider(app.Tab1);
            app.BinWidthSlider.Limits = [1 11];
            app.BinWidthSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.BinWidthSlider.ValueChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.BinWidthSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.BinWidthSlider.MinorTicks = [];
            app.BinWidthSlider.Value = 5;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Data';
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Last Name'; 'Gender'; 'Smoker'; 'Age'; 'Height'; 'Weight'; 'Diastolic'; 'Systolic'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = PatientsDisplay

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end