classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % for function f(x) = ax^3 + bx^2 + cx + d
            a=-5; b=4; c=2; d=7;
            
            f2 = @(x) a*x^3 + b*x^2 + c*x + d;
            df2 = @(x) 3*a*x^2 + 2*b*x + c;
            
            n=1000;
            count=1;
            tol=1e-7;
            
            % x1=-1;
            % x2=1/2+1i*(sqrt(3)/2);
            % x3=1/2-1i*(sqrt(3)/2);
            r = roots([a b c d]);
            
            x1=r(1);
            x2=r(2);
            x3=r(3);
            
            
            a=linspace(0,1,n);
            b=linspace(0,1,n);
            col=zeros(n, n);
            
            for i=1:length(a)
                
                if mod(i, 100)==0
                    i;
                end
                for j=1:length(b)
                    root = newton(f2, df2, (a(i)+b(j)*1i), 1e-12);
                    if(abs(root-x1)<tol)
                        col(i,j)=1; %converges to x1
                    elseif(abs(root-x2)<tol)
                        col(i,j)=2; %converges to x2
                    elseif(abs(root-x3)<tol)
                        col(i,j)=3; %converges to x3
                    end
                end
                count=count+1;
            end
            h=pcolor(app.UIAxes, a,b,col)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end